classdef Fulltilt_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UserScreenLabel           matlab.ui.control.Label
        RoboticDesignLabel        matlab.ui.control.Label
        Connect4ProjectDemoLabel  matlab.ui.control.Label
        Player2Label              matlab.ui.control.Label
        Player1Label              matlab.ui.control.Label
        ChoosePathLabel           matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        PickYellowButton          matlab.ui.control.Button
        PickRedButton             matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        NewGameButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,Base]=sim.simxGetObjectHandle(clientID,'Base',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetObjectPosition(clientID,Base,-1,[0, 0, 3.37],sim.simx_opmode_blocking);
      pause(3)
     [returnCode]=sim.simxSetObjectPosition(clientID,Base,-1,[0, 2.925, 3.37],sim.simx_opmode_blocking);


        end

        % Button pushed function: PickRedButton
        function PickRedButtonPushed(app, event)
    sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
    sim.simxFinish(-1); % just in case, close all opened connections
    clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
    % [returnCode,Coin_R]=sim.simxGetObjectHandle(clientID,'Coin_R',sim.simx_opmode_blocking);
    [returnCode,PickUp]=sim.simxGetObjectHandle(clientID,'PickUp',sim.simx_opmode_blocking);
    [returnCode,ForceSensor]=sim.simxGetObjectHandle(clientID,'ForceSensor',sim.simx_opmode_blocking);
    [returnCode,ProSensor]=sim.simxGetObjectHandle(clientID,'ProSensor',sim.simx_opmode_blocking);
    [returnCode,BackForward]=sim.simxGetObjectHandle(clientID,'BackForward',sim.simx_opmode_blocking);
    [returnCode,LeftRight]=sim.simxGetObjectHandle(clientID,'LeftRight',sim.simx_opmode_blocking);
    [returnCode,UpDown]=sim.simxGetObjectHandle(clientID,'UpDown',sim.simx_opmode_blocking);
    [returnCode,ProSensor]=sim.simxGetObjectHandle(clientID,'ProSensor',sim.simx_opmode_blocking);

    % Vision Feedback
    [returnCode,camera]=sim.simxGetObjectHandle(clientID,'ScreenObserver',sim.simx_opmode_blocking); 
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_streaming);  
    pause(0.2);
    % Capture one image after each pick
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_buffer);
    imwrite(image,'test.png')
    imshow('test.png', 'Parent', app.UIAxes)
    
    % Connect4 Vision Sensor_Shun
        readimage = 0;
        while readimage == 0
        [returnCode,camera]=sim.simxGetObjectHandle(clientID,'VisionSensor',sim.simx_opmode_blocking); 
        [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_streaming);  
        pause(0.1);
        % Capture one image after each pick
        [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_buffer);
        % imshow(image)
           if sum(image,'all')>0
               readimage = 1;
           end
        end
        % Red coins location extract
        rgdif = image(:,:,1)-image(:,:,2);
        % Number of visible red coins
        n_red = sum(rgdif(:,:)>150,'all');
        % Red coin pick movement
        redcoinpick = zeros(n_red,2);
        i = 0;
        for y = 1:5
            for x = 1:9
               if rgdif(y,x) > 200
                   i = i + 1;
                   % x-axis movement
                   redcoinpick(i,1) = 0.33*(x - 5);
                   % y-axis movement
                   redcoinpick(i,2) = 0.30*(y - 3);
               end
            end
        end
        
        % Print robotic arm movement parameters
        redcoinpick
     randi_red = randi(n_red);
     [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,redcoinpick(randi_red,2),sim.simx_opmode_blocking); 
     pause(0.5)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,redcoinpick(randi_red,1),sim.simx_opmode_blocking); 
     pause(0.5)
     
    %Pick_up
     [returnCode]=sim.simxSetJointTargetPosition(clientID,PickUp,2.75,sim.simx_opmode_blocking); 
     pause(2)
     [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_streaming);
     pause(0.5)
     [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_buffer);
     if (detectedObjectHandle == 13)
        [returnCode]=sim.simxSetJointTargetPosition(clientID,PickUp,-2.75,sim.simx_opmode_blocking); 
        [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,0,sim.simx_opmode_blocking); 
        pause(0.1)
        [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,0,sim.simx_opmode_blocking); 
        pause(0.1)
     else 
        if not (detectedObjectHandle == 0)

        [returnCode]=sim.simxSetObjectParent(clientID,detectedObjectHandle,ForceSensor,1,sim.simx_opmode_blocking);
         end

     
     pause(0.1)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,PickUp,-2.75,sim.simx_opmode_blocking); 

     %Ready to release
     [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,1.85,sim.simx_opmode_blocking); 
     pause(0.1)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,2,sim.simx_opmode_blocking); 
     pause(0.1)
     [returnCode]=sim.simxSetJointTargetVelocity(clientID,UpDown,1,sim.simx_opmode_blocking);
      pause(5)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,0,sim.simx_opmode_blocking); 
     pause(2)

     %release  
    [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_streaming);
    pause(0.1)
    [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_buffer);
    
    [returnCode]=sim.simxSetObjectParent(clientID,detectedObjectHandle,-1,1,sim.simx_opmode_blocking);
    
    %Back to Origin
    [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,2,sim.simx_opmode_blocking);
    pause(1.5)
    [returnCode]=sim.simxSetJointTargetVelocity(clientID,UpDown,-1,sim.simx_opmode_blocking);
    pause(2)
    
    % Vision Feedback
    [returnCode,camera]=sim.simxGetObjectHandle(clientID,'ScreenObserver',sim.simx_opmode_blocking); 
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_streaming);  
    pause(0.2);
    % Capture one image after each pick
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_buffer);
    imwrite(image,'test.png')
    imshow('test.png', 'Parent', app.UIAxes)
    
    pause(2)
    [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,0,sim.simx_opmode_blocking);
    [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,0,sim.simx_opmode_blocking);

     end
    
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,RevoCoin]=sim.simxGetObjectHandle(clientID,'RevoCoin',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetJointTargetPosition(clientID,RevoCoin,(-39*pi)/180, sim.simx_opmode_blocking);       
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,RevoCoin]=sim.simxGetObjectHandle(clientID,'RevoCoin',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetJointTargetPosition(clientID,RevoCoin,(-26*pi)/180, sim.simx_opmode_blocking);        
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,RevoCoin]=sim.simxGetObjectHandle(clientID,'RevoCoin',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetJointTargetPosition(clientID,RevoCoin,(-13*pi)/180, sim.simx_opmode_blocking);        
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,RevoCoin]=sim.simxGetObjectHandle(clientID,'RevoCoin',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetJointTargetPosition(clientID,RevoCoin,(0*pi)/180, sim.simx_opmode_blocking);        
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,RevoCoin]=sim.simxGetObjectHandle(clientID,'RevoCoin',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetJointTargetPosition(clientID,RevoCoin,(13*pi)/180, sim.simx_opmode_blocking);        
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,RevoCoin]=sim.simxGetObjectHandle(clientID,'RevoCoin',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetJointTargetPosition(clientID,RevoCoin,(26*pi)/180, sim.simx_opmode_blocking); 
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
     [returnCode,RevoCoin]=sim.simxGetObjectHandle(clientID,'RevoCoin',sim.simx_opmode_blocking);
     
     [returnCode]=sim.simxSetJointTargetPosition(clientID,RevoCoin,(39*pi)/180, sim.simx_opmode_blocking);        
        end

        % Button pushed function: PickYellowButton
        function PickYellowButtonPushed(app, event)
         
         sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
    sim.simxFinish(-1); % just in case, close all opened connections
    clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);
    % [returnCode,Coin_R]=sim.simxGetObjectHandle(clientID,'Coin_R',sim.simx_opmode_blocking);
    [returnCode,PickUp]=sim.simxGetObjectHandle(clientID,'PickUp',sim.simx_opmode_blocking);
    [returnCode,ForceSensor]=sim.simxGetObjectHandle(clientID,'ForceSensor',sim.simx_opmode_blocking);
    [returnCode,ProSensor]=sim.simxGetObjectHandle(clientID,'ProSensor',sim.simx_opmode_blocking);
    [returnCode,BackForward]=sim.simxGetObjectHandle(clientID,'BackForward',sim.simx_opmode_blocking);
    [returnCode,LeftRight]=sim.simxGetObjectHandle(clientID,'LeftRight',sim.simx_opmode_blocking);
    [returnCode,UpDown]=sim.simxGetObjectHandle(clientID,'UpDown',sim.simx_opmode_blocking);
    [returnCode,ProSensor]=sim.simxGetObjectHandle(clientID,'ProSensor',sim.simx_opmode_blocking);

    % Vision Feedback
    [returnCode,camera]=sim.simxGetObjectHandle(clientID,'ScreenObserver',sim.simx_opmode_blocking); 
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_streaming);  
    pause(0.2);
    % Capture one image after each pick
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_buffer);
    imwrite(image,'test.png')
    imshow('test.png', 'Parent', app.UIAxes)
    
    % Connect4 Vision Sensor_Shun
        readimage = 0;
        while readimage == 0
        [returnCode,camera]=sim.simxGetObjectHandle(clientID,'VisionSensor',sim.simx_opmode_blocking); 
        [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_streaming);  
        pause(0.1);
        % Capture one image after each pick
        [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_buffer);
        % imshow(image)
           if sum(image,'all')>0
               readimage = 1;
           end
        end
        
        % Yellow coins location extract
        gbdif = image(:,:,2)-image(:,:,3);
        % Number of visible yellow coins
        n_yellow = sum(gbdif(:,:)>150,'all');
        % Yellow coin pick movement
        yellowcoinpick = zeros(n_yellow,2);
        i = 0;
        for y = 1:5
            for x = 1:9
               if gbdif(y,x) > 200
                   i = i + 1;
                   % x-axis movement
                   yellowcoinpick(i,1) = 0.33*(x - 5);
                   % y-axis movement
                   yellowcoinpick(i,2) = 0.30*(y - 3);
               end
            end
        end
        % Print robotic arm movement parameters
        yellowcoinpick
     randi_yellow = randi(n_yellow);
     [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,yellowcoinpick(randi_yellow,2),sim.simx_opmode_blocking); 
     pause(0.5)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,yellowcoinpick(randi_yellow,1),sim.simx_opmode_blocking); 
     pause(0.5)
    
    %Pick_up
     [returnCode]=sim.simxSetJointTargetPosition(clientID,PickUp,2.75,sim.simx_opmode_blocking); 
     pause(2)
     [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_streaming);
     pause(0.5)
     [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_buffer);
     
     if (detectedObjectHandle == 13)
        [returnCode]=sim.simxSetJointTargetPosition(clientID,PickUp,-2.75,sim.simx_opmode_blocking); 
        [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,0,sim.simx_opmode_blocking); 
        pause(0.1)
        [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,0,sim.simx_opmode_blocking); 
        pause(0.1)
     else 
     
     if not (detectedObjectHandle == 0)
        [returnCode]=sim.simxSetObjectParent(clientID,detectedObjectHandle,ForceSensor,1,sim.simx_opmode_blocking);
     end
     pause(0.1)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,PickUp,-2.75,sim.simx_opmode_blocking); 

     %Ready to release
     [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,1.85,sim.simx_opmode_blocking); 
     pause(0.1)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,2,sim.simx_opmode_blocking); 
     pause(0.1)
     [returnCode]=sim.simxSetJointTargetVelocity(clientID,UpDown,1,sim.simx_opmode_blocking);
     pause(5)
     [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,0,sim.simx_opmode_blocking); 
     pause(2)

     %release  
    [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_streaming);
    pause(0.1)
    [returnCode,detectionState,detectedPoint,detectedObjectHandle,arraydetectedSurfaceNormalVector]=sim.simxReadProximitySensor(clientID,ProSensor,sim.simx_opmode_buffer);
    
    [returnCode]=sim.simxSetObjectParent(clientID,detectedObjectHandle,-1,1,sim.simx_opmode_blocking);

    %Back to Origin
    [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,2,sim.simx_opmode_blocking);
    pause(1.5)
    [returnCode]=sim.simxSetJointTargetVelocity(clientID,UpDown,-1,sim.simx_opmode_blocking);
    pause(2)
    
    % Vision Feedback
    [returnCode,camera]=sim.simxGetObjectHandle(clientID,'ScreenObserver',sim.simx_opmode_blocking); 
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_streaming);  
    pause(0.2);
    % Capture one image after each pick
    [returnCode,resolution,image]=sim.simxGetVisionSensorImage2(clientID,camera,0,sim.simx_opmode_buffer);
    imwrite(image,'test.png')
    imshow('test.png', 'Parent', app.UIAxes)
    
    pause(2)
    [returnCode]=sim.simxSetJointTargetPosition(clientID,LeftRight,0,sim.simx_opmode_blocking);
    [returnCode]=sim.simxSetJointTargetPosition(clientID,BackForward,0,sim.simx_opmode_blocking); 
     end 
        end

        % Callback function
        function UIAxesButtonDown(app, event)
     sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
     sim.simxFinish(-1); % just in case, close all opened connections
     clientID=sim.simxStart('127.0.0.1',19999,true,true,5000,5);

     [returnCode,ScreenObserver]=sim.simxGetObjectHandle(clientID,'ScreenObserver',sim.simx_opmode_blocking);
     
     
     %display screenObserver
     %[returnCode,camhandle]=sim.simxGetObjectHandle(clientID,'ScreenObserver',sim.simx_opmode_oneshot_wait);
     %[returnCode,resolution,img]=sim.simxGetVisionSensorImage2(clientID,camhandle,0,sim.simx_opmode_oneshot_wait);
     %imshow(img)
     %exportgraphics(figure,'/Users/rex_1998/Desktop/PSU_Study/BME_Courses/2022_Fall_ME_556_Robotic_Concepts/2022_10_12/test.png')
     %pause(3)
     %imshow('test.png', 'Parent', app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Ⅰ';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Ⅱ';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Ⅲ';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Ⅳ';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'Ⅴ';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = 'Ⅵ';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = 'Ⅶ';
            app.PickRedButton = uibutton(app.UIFigure, 'push');
            app.PickRedButton.ButtonPushedFcn = createCallbackFcn(app, @PickRedButtonPushed, true);
            app.PickRedButton.Text = 'Pick Red';
            app.PickYellowButton = uibutton(app.UIFigure, 'push');
            app.PickYellowButton.ButtonPushedFcn = createCallbackFcn(app, @PickYellowButtonPushed, true);
            app.PickYellowButton.Text = 'Pick Yellow';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'C4design.png';
            app.ChoosePathLabel = uilabel(app.UIFigure);
            app.ChoosePathLabel.Text = 'Choose Path';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Connect4ProjectDemoLabel = uilabel(app.UIFigure);
            app.Connect4ProjectDemoLabel.Text = 'Connect 4 Project Demo';
            app.RoboticDesignLabel = uilabel(app.UIFigure);
            app.RoboticDesignLabel.Text = 'Robotic Design';
            app.UserScreenLabel = uilabel(app.UIFigure);
            app.UserScreenLabel.Text = 'User Screen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fulltilt_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end